classdef viena_R2017b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VIENAUIFigure                 matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MOTORTab                      matlab.ui.container.Tab
        MotorMeasurementsPanel        matlab.ui.container.Panel
        NrrpmEditFieldLabel           matlab.ui.control.Label
        Nr_model                      matlab.ui.control.NumericEditField
        IsAEditFieldLabel             matlab.ui.control.Label
        Is_model                      matlab.ui.control.NumericEditField
        UsVEditFieldLabel             matlab.ui.control.Label
        Us_model                      matlab.ui.control.NumericEditField
        fHzEditField_2Label           matlab.ui.control.Label
        f_model                       matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        CircuitInputsPanel            matlab.ui.container.Panel
        T_loadEditFieldLabel          matlab.ui.control.Label
        loadTorque                    matlab.ui.control.NumericEditField
        EnableSwitch                  matlab.ui.control.Switch
        STOPButton                    matlab.ui.control.Button
        MotorInputsPanel              matlab.ui.container.Panel
        VfEditFieldLabel              matlab.ui.control.Label
        VoF                           matlab.ui.control.NumericEditField
        NsrpmLabel                    matlab.ui.control.Label
        statorSpeed                   matlab.ui.control.NumericEditField
        sweepCheckBox                 matlab.ui.control.CheckBox
        sweepStatorSpeed              matlab.ui.control.NumericEditField
        EnableLampLabel               matlab.ui.control.Label
        EnableLamp                    matlab.ui.control.Lamp
        SINAMICSTab                   matlab.ui.container.Tab
        SINAMICSPanel                 matlab.ui.container.Panel
        EMCYLamp                      matlab.ui.control.Lamp
        VFMinEditFieldLabel           matlab.ui.control.Label
        VFMinEditField                matlab.ui.control.NumericEditField
        TorqueEditFieldLabel          matlab.ui.control.Label
        TorqueEditField               matlab.ui.control.NumericEditField
        CurrentEditFieldLabel         matlab.ui.control.Label
        CurrentEditField              matlab.ui.control.NumericEditField
        VelocityEditFieldLabel        matlab.ui.control.Label
        VelocityEditField             matlab.ui.control.NumericEditField
        VFFreqEditFieldLabel          matlab.ui.control.Label
        VFFreqEditField               matlab.ui.control.NumericEditField
        TargetVelocityEditFieldLabel  matlab.ui.control.Label
        TargetVelocityEditField       matlab.ui.control.NumericEditField
        VFVoltageEditFieldLabel       matlab.ui.control.Label
        VFVoltageEditField            matlab.ui.control.NumericEditField
        StateEditFieldLabel           matlab.ui.control.Label
        StateEditField                matlab.ui.control.EditField
        EMCYEditFieldLabel            matlab.ui.control.Label
        TerminalWindowPanel           matlab.ui.container.Panel
        TerminalWindowText            matlab.ui.control.TextArea
        EPOSTab                       matlab.ui.container.Tab
        EPOSDeviceControlPanel        matlab.ui.container.Panel
        EPOSFaultReset                matlab.ui.control.Button
        EPOSActivate                  matlab.ui.control.Button
        EPOSShutdown                  matlab.ui.control.Button
        EPOSHalt                      matlab.ui.control.Button
        EPOSStatePanel                matlab.ui.container.Panel
        EPOSState                     matlab.ui.control.TextArea
        EPOSEnablePositionMode        matlab.ui.control.StateButton
        EPOSPIDGainsPanel             matlab.ui.container.Panel
        EPOSApplyPIDGains             matlab.ui.control.Button
        PEditFieldLabel               matlab.ui.control.Label
        EPOS_P_Gain                   matlab.ui.control.NumericEditField
        IEditFieldLabel               matlab.ui.control.Label
        EPOS_I_Gain                   matlab.ui.control.NumericEditField
        DEditFieldLabel               matlab.ui.control.Label
        EPOS_D_Gain                   matlab.ui.control.NumericEditField
        MaxFollowingErrorPanel        matlab.ui.container.Panel
        EPOSMaxFollowingError         matlab.ui.control.NumericEditField
        EPOSApplyMaxFolloingError     matlab.ui.control.Button
        EPOSCurrentPositionPanel      matlab.ui.container.Panel
        EPOSCurrentPosition           matlab.ui.control.TextArea
        EPOSCurrentPositionGauge      matlab.ui.control.Gauge
        EPOSSetPositionPanel          matlab.ui.container.Panel
        EPOSSetPosition               matlab.ui.control.Button
        EPOSSetPositionValue          matlab.ui.control.NumericEditField
        EPOSLogPanel                  matlab.ui.container.Panel
        EPOSLog                       matlab.ui.control.TextArea
        BATTERYTab                    matlab.ui.container.Tab
        TSonoffButton                 matlab.ui.control.Button
        ResetErrorButton              matlab.ui.control.Button
        OverrideButton                matlab.ui.control.Button
        TSOnLampLabel                 matlab.ui.control.Label
        TSOnLamp                      matlab.ui.control.Lamp
        BMS1ErrorLampLabel            matlab.ui.control.Label
        BMS1ErrorLamp                 matlab.ui.control.Lamp
        BMS2ErrorLampLabel            matlab.ui.control.Label
        BMS2ErrorLamp                 matlab.ui.control.Lamp
        ChargePanel                   matlab.ui.container.Panel
        SOCGaugeLabel                 matlab.ui.control.Label
        SOCGauge                      matlab.ui.control.LinearGauge
        CurrentValue                  matlab.ui.control.Label
        CurrentLabel                  matlab.ui.control.Label
        VoltageLabel                  matlab.ui.control.Label
        VoltegeValue                  matlab.ui.control.Label
        EnergyPanel                   matlab.ui.container.Panel
        CurrentModeLabel              matlab.ui.control.Label
        RateLabel                     matlab.ui.control.Label
        BalancingRateLabel            matlab.ui.control.Label
        CurrentRateValue              matlab.ui.control.Label
        RateValue                     matlab.ui.control.Label
        BalancingRateValue            matlab.ui.control.Label
        CellVoltagesPanel             matlab.ui.container.Panel
        HighestCellVoltLabel          matlab.ui.control.Label
        AveregeCellVoltLabel          matlab.ui.control.Label
        LowestCellVoltLabel           matlab.ui.control.Label
        HighestCellVoltValue          matlab.ui.control.Label
        AveregeCellVoltValue          matlab.ui.control.Label
        LowestCellVoltValue           matlab.ui.control.Label
        CellTemperaturesPanel         matlab.ui.container.Panel
        HighestCellTempLabel          matlab.ui.control.Label
        AveregeCellTempLabel          matlab.ui.control.Label
        LowestCellTempLabel           matlab.ui.control.Label
        HighestCellTempValue          matlab.ui.control.Label
        AveregeCellTempValue          matlab.ui.control.Label
        LowestCellTempValue           matlab.ui.control.Label
        SlaveTemperaturesPanel        matlab.ui.container.Panel
        HighestUPLabel                matlab.ui.control.Label
        HighestLoadLabel              matlab.ui.control.Label
        HighestUPValue                matlab.ui.control.Label
        HighestLoadValue              matlab.ui.control.Label
        LogoButton                    matlab.ui.control.Button
        MQTTLabel                     matlab.ui.control.Label
        BatteriesLampLabel            matlab.ui.control.Label
        BatteriesLamp                 matlab.ui.control.Lamp
        RPiLampLabel                  matlab.ui.control.Label
        RPiLamp                       matlab.ui.control.Lamp
        CANLampLabel                  matlab.ui.control.Label
        CANLamp                       matlab.ui.control.Lamp
        BrokerLampLabel               matlab.ui.control.Label
        BrokerLamp                    matlab.ui.control.Lamp
        Label                         matlab.ui.control.Label
        BrokerConnectButton           matlab.ui.control.Button
        ServerPort                    matlab.ui.control.NumericEditField
        ServerAddress                 matlab.ui.control.EditField
    end

    
    properties (Access = private)        
        %Motor
        Motor_ = VIENAGUI.InductionMachine;
        rotorSpeed_          = 0;
        statorSpeedPrevious_ = 0;
        frequency_           = 0;
        voltage_             = 0;
        statorCurrent_       = 0;
        statorSpeed_         = 0;
        loadTorque_          = 0;
        loadTorquePrevious_  = 0;
        %MQTT
        Mqtt_    = NaN;
        brokerConnected_     = false;
        %MQTTSubscription
        MqttSubLog_ = NaN;
        MqttSubrpi_ = NaN;
        MqttSubCan_ = NaN;
        %SINAICS
        MqttSINStateRead_ = NaN;
        MqttSINStateWrite_ = NaN;
        MqttSINTargetVelocityRead_ = NaN;
        MqttSINTargetVelocityWrite_ = NaN;
        MqttSINVofVoltageRead_ = NaN;
        MqttSINVofVoltageWrite_ = NaN;
        MqttSINVofFreqRead_ = NaN;
        MqttSINVofFreqWrite_ = NaN;
        MqttSIN_EMCY_ = NaN;
        MqttSINVelocity_ = NaN;
        MqttSINTemperature_ = NaN;
        MqttSINCurrent_ = NaN;
        MqttSINConnected_ = NaN;
        %EPOS
        MqttEPOSConnected_ = NaN;
        MqttEPOSStateRead_ = NaN;
        MqttEPOSStateWrite_ = NaN;
        MqttEPOS_PID_Read_ =NaN;
        MqttEPOS_PID_Write_ = NaN;
        MqttEPOSTargetPositionRead_ = NaN;
        MqttEPOSTargetPositionWrite_ = NaN;
        MqttEPOSPosition_ = NaN;
        MqttEPOSAngleRead_ = NaN;
        MqttEPOSAngleWrite_ = NaN;
        MqttEPOSMaxFollowErrRead_ = NaN;
        MqttEPOSMaxFollowErrWrite_ = NaN;
        MqttEPOS_EMCY_ = NaN;
        %BATTERY
        MqttBATTERY_TS = NaN;
        MqttBATTERYOverride = NaN;
        MqttBATTERYCellVoltHigh = NaN;
        MqttBATTERYCellVoltAv = NaN;
        MqttBATTERYCellVoltLow = NaN;
        MqttBATTERYChargeSOC = NaN;
        MqttBATTERYChargeVolt = NaN;
        MqttBATTERYChargeCurrent= NaN;
        MqttBATTERYCellTempHigh = NaN;
        MqttBATTERYCellTempAv = NaN;
        MqttBATTERYCellTempLow = NaN;
        MqttBATTERYEnergyCurrentMode = NaN;
        MqttBATTERYEnergyRate = NaN;
        MqttBATTERYEnergyBalancingRate = NaN;
        MqttBATTERYSlaveTempHighestuP = NaN;
        MqttBATTERYSlaveTempHighestLoad = NaN;


        %SINAMICS State Container
        SINStateMap = containers.Map([-1 0 1 2 3 4 5 6 7 8 9 10 11],{'Unknown','start',...
            'not ready to switch on','switch on disable','ready to switch on',...
            'switched on','refresh','measure init','operation enable',...
            'quick stop active','fault reaction active (disabled)',...
            'fault reaction active (enable)','fault'});
        
        
    end
    
    
    methods (Access = private)
        
        function refresh(app)
            %REFRESH - function that refreshes the GUI Plot
            
            %check if sweep is active and value of sweepSpeed
            isSweepEnable = app.sweepCheckBox.Value;
            sweepSpeed_aux = app.sweepStatorSpeed.Value;
            %if conditions are met, do a sweep in frequency
            if isSweepEnable && sweepSpeed_aux
                %get values of previous stator speed, stator speed, frequency and voltage
                speedPrev_aux = app.statorSpeedPrevious_;
                statorSpeed_aux = app.statorSpeed.Value;
                %checks if motor is increasing or decreasing frequency
                isPositive = sign(statorSpeed_aux - speedPrev_aux);
                %creates frequency array
                frequency_aux = (speedPrev_aux:(isPositive*sweepSpeed_aux):statorSpeed_aux)*(app.Motor_.polePair)/60;
                %array sometimes does not finish in the desired input statorSpeed
                frequency_aux = [frequency_aux statorSpeed_aux*(app.Motor_.polePair)/60];
                voltage_aux = (app.VoF.Value)*(frequency_aux);
                %sweeps stator frequencies
                for kFreq = 1:1:numel(frequency_aux)
                    %if STOP button is pressed break out of cycle.
                    if app.STOPButton.UserData
                        app.STOPButton.UserData = false;
                        break
                    end
                    %values in app so it updates in page
                    app.voltage_ = voltage_aux(kFreq);
                    app.frequency_ = frequency_aux(kFreq);
                    %get values to plot in the GUI
                    appValues = app.Motor_.getValues4App(app.voltage_,app.frequency_);
                    %check if new Motor_ state is possible and if it is, compute it
                    [solutions,flag] = app.Motor_.getRotorSpeed(app.voltage_,app.frequency_,app.rotorSpeed_,app.loadTorque.Value);
                    %get values of rotor Speed and Stator Current (for cases where there is no solution, motor stalls)
                    getRotorSpeedStatorCurrent(app,solutions,flag);
                    %updates graphics and main panels
                    refresh_graphics(app,appValues(:,end),appValues(:,4));
                    refresh_panel(app);
                end
            %does not sweep
            else
                app.frequency_ = app.statorSpeed.Value*(app.Motor_.polePair)/60;
                app.voltage_ = (app.VoF.Value)*(app.frequency_);
                appValues = app.Motor_.getValues4App(app.voltage_,app.frequency_);
                [solutions,flag] = app.Motor_.getRotorSpeed(app.voltage_,app.frequency_,app.rotorSpeed_,app.loadTorque.Value);
                getRotorSpeedStatorCurrent(app,solutions,flag);
                refresh_graphics(app,appValues(:,end),appValues(:,4))
                refresh_panel(app);
            end
            app.statorSpeedPrevious_ = app.statorSpeed.Value;
        end
        
        function MessageHandler(app,topic,data)
            switch topic
                
                %SINAMICS
                case char('VIENA/SINAMICS/state/read')
                    app.Mqtt_.SIN_state = uint8(data.char); %MUDAR O TIPO DE DADOS DO SIN_state
                    app.StateEditField.Value = app.SINStateMap(app.Mqtt_.SIN_state);
                  
                case char('VIENA/SINAMICS/target_velocity/read')
                    app.Mqtt_.SIN_targetVelocity = typecast(uint8(data.char),'int32'); %int32
                    
                case char('VIENA/SINAMICS/vof_voltage/read')
                    app.Mqtt_.SIN_vofVoltage = typecast(uint8(data.char),'single'); %float
                    
                case char('VIENA/SINAMICS/vof_freq/read')
                    app.Mqtt_.SIN_vofFrequency = typecast(uint8(data.char),'single'); %float
                    
                case char('VIENA/SINAMICS/vof_min/read')
                    app.Mqtt_.SIN_vofVoltageMin = typecast(uint8(data.char),'single'); %float
                    
                case char('VIENA/SINAMICS/EMCY')
                    app.Mqtt_.SIN_EMCY = data.char; %char
                    
                case char('VIENA/SINAMICS/velocity')
                    app.Mqtt_.SIN_velocity = double(typecast(uint8(data.char),'int32')); %int32
                    app.VelocityEditField.Value = app.Mqtt_.SIN_velocity;
                    
                case char('VIENA/SINAMICS/temperature')
                    app.Mqtt_.SIN_temperature = typecast(uint8(data.char),'int32'); %int32 ver o que é depois
                    
                case char('VIENA/SINAMICS/current_smoothed')
                    app.Mqtt_.SIN_currentSmoothed = typecast(uint8(data.char),'single'); %float
                    app.CurrentEditField.Value = app.Mqtt_.SIN_currentSmoothed;
                    
                case char('VIENA/SINAMICS/torque_smoothed')
                    app.Mqtt_.SIN_torqueSmoothed = typecast(uint8(data.char),'single'); %float
                    
                case char('VIENA/SINAMICS/connected')
                    app.Mqtt_.SIN_connected = typecast(uint8(data.char),'uint8'); %uint8
                  
                %General
                case char('VIENA/General/canopen')
                    app.Mqtt_.CAN_connected = typecast(uint8(data.char),'uint8'); %uint8
                    if app.Mqtt_.CAN_connected
                        app.CANLamp.Color = [0 1 0];
                    else
                        app.CANLamp.Color = [1 0 0];
                    end
                    
                case char('VIENA/General/rpi')
                    app.Mqtt_.RPI_connected = typecast(uint8(data.char),'uint8'); %uint8
                    if app.Mqtt_.RPI_connected
                        app.RPiLamp.Color = [0 1 0];
                    else
                        app.RPiLamp.Color = [1 0 0];
                    end
                    
                case char('VIENA/General/log')
                    app.Mqtt_.LOG = data;
                    app.TerminalWindowText.Value = [{char(data)};app.TerminalWindowText.Value];
                    app.EPOSLog.Value = [{char(data)};app.TerminalWindowText.Value];
                    
                %EPOS
                case char('VIENA/EPOS/connected/read') 
                    
                case char('VIENA/EPOS/state/read')
                    app.Mqtt_.EPOS_state = uint8(data.char); %MUDAR O TIPO DE DADOS DO SIN_state
                    app.EPOSState.Value = app.SINStateMap(app.Mqtt_.EPOS_state); %Mudar o SINStateMap
                    
                case char('VIENA/EPOS/PID/read')
                                        
                case char('VIENA/EPOS/target_position/read')
                    
                case char('VIENA/EPOS/position/read')
                    
                case char('VIENA/EPOS/angle/read')
                    
                case char('VIENA/EPOS/MAX_FOLLOWING_ERROR/read');
                    
                case char('VIENA/EPOS/EMCY/read');
                    
                %BATTERY
                case char('VIENA/BATTERY/TS/read')
                    
                case char('VIENA/BATTERY/TS/write')
                    
                case char('VIENA/BATTERY/cell_voltage_highest/read')
                    
                case char('VIENA/BATTERY/cell_voltage_averege/read')
                    
                case char('VIENA/BATTERY/cell_voltage_lowest/read')
                    
                case char('VIENA/BATTERY/charge_SOC/read')
                    
                case char('VIENA/BATTERY/charge_voltage/read')
                    
                case char('VIENA/BATTERY/charge_current/read')
                    
                case char('VIENA/BATTERY/cell_temp_highest/read')
                    
                case char('VIENA/BATTERY/cell_temp_averege/read')
                    
                case char('VIENA/BATTERY/cell_temp_lowest/read')
                    
                case char('VIENA/BATTERY/energy_current_mode/read')
                    
                case char('VIENA/BATTERY/energy_rate/read')
                    
                case char('VIENA/BATTERY/energy_balancing_rate/read')
                    
                case char('VIENA/BATTERY/slave_temp_highest_uP/read')
                    
                case char('VIENA/BATTERY/slave_temp_highest_load/read')
                   
                otherwise
                    warning('no topic of that name.')
            end
            
        end
    end
    
    methods (Access = private, Hidden = true)
        
        function refresh_graphics(app,x,y)
            %plot in the GUI
            plot(app.UIAxes,x,y)
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.rotorSpeed_,app.loadTorque.Value,'ro');
            hold(app.UIAxes, 'off');
            drawnow limitrate
        end
        
        function refresh_panel(app)
            %update realtime
            app.f_model.Value    = app.frequency_;
            app.Us_model.Value   = app.voltage_;
            app.Is_model.Value   = app.statorCurrent_;
            app.IsGauge.Value    = app.statorCurrent_;
            app.Nr_model.Value   = app.rotorSpeed_;
            app.SpeedGauge.Value = 2*pi/60*app.rotorSpeed_/8*0.25*3.6;
        end
        
        function getRotorSpeedStatorCurrent(app,s,flag)
            if (flag == 0)
                app.rotorSpeed_ = 0;
                if (app.voltage_ == 0)
                    app.statorCurrent_ = 0;
                else
                    solution = app.Motor_.EQSolutions(app.voltage_,app.frequency_,app.rotorSpeed_);
                    app.statorCurrent_= solution(1);
                end
                
            else
                app.rotorSpeed_ = s(1);
                app.statorCurrent_  = s(2);
            end
        end
        
        function changeEnableSwitch(app)
            switchValue = app.EnableSwitch.Value;
            isSwitchOn = strcmp(switchValue,'On');
            if isSwitchOn
                app.VoF.Enable='On';
                app.statorSpeed.Enable='On';
                app.sweepCheckBox.Enable = 'On';
                if app.sweepCheckBox.Value
                    app.sweepStatorSpeed.Enable = 'On';
                end
                app.loadTorque.Enable = 'On';
                app.EnableLamp.Color=[0,1,0];
            else
                app.VoF.Enable='Off';
                app.statorSpeed.Enable ='Off';
                app.sweepCheckBox.Enable = 'Off';
                app.sweepStatorSpeed.Enable = 'Off';
                app.loadTorque.Enable = 'Off';
                app.EnableLamp.Color=[1,0,0];
            end
        end
    
        function switchOffAll(app)
            app.f_model.Value    = 0;
            app.Us_model.Value   = 0;
            app.Is_model.Value   = 0;
            app.IsGauge.Value    = 0;
            app.Nr_model.Value   = 0;
            app.SpeedGauge.Value = 0;
            app.VoF.Value = 0;
            app.statorSpeed.Value = 0;
            app.NsSlider.Value = 0;
            app.loadTorque.Value = 0;
            app.sweepCheckBox.Value = 0;
            app.sweepStatorSpeed.Value = 0;
            app.EnableSwitch.Value = 'Off';
            changeEnableSwitch(app);
            cla(app.UIAxes);
        end
    
        function MQTTConnect(app)
            app.Mqtt_ = VIENAGUI.MQTTController;
        end
    
        function MQTTSubscribeAll(app)
            %MQTTSubscription
            app.MqttSubLog_ = app.Mqtt_.MQTTSubscribe('VIENA/General/log',0,@app.MessageHandler);
            app.MqttSubrpi_ = app.Mqtt_.MQTTSubscribe('VIENA/General/rpi',0,@app.MessageHandler);
            app.MqttSubCan_ = app.Mqtt_.MQTTSubscribe('VIENA/General/canopen',0,@app.MessageHandler);
            %MQTTSinamics
            app.MqttSINStateRead_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/state/read',0,@app.MessageHandler);
            app.MqttSINTargetVelocityRead_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/target_velocity/read',0,@app.MessageHandler);
            app.MqttSINVofVoltageRead_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/vof_voltage/read',0,@app.MessageHandler);
            app.MqttSINVofFreqRead_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/vof_freq/read',0,@app.MessageHandler);
            app.MqttSIN_EMCY_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/EMCY',0,@app.MessageHandler);
            app.MqttSINVelocity_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/velocity',0,@app.MessageHandler);
            app.MqttSINTemperature_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/temperature',0,@app.MessageHandler);
            app.MqttSINCurrent_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/current_smoothed',0,@app.MessageHandler);
            app.MqttSINConnected_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/connected',0,@app.MessageHandler);
            %MQTT_EPOS
            app.MqttEPOSConnected_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/connected',0,@app.MessageHandler);
            app.MqttEPOSStateRead_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/state',0,@app.MessageHandler);
            app.MqttEPOS_PID_Read_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/PID',0,@app.MessageHandler);
            app.MqttEPOSTargetPositionRead_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/target_position',0,@app.MessageHandler);
            app.MqttEPOSPosition_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/position',0,@app.MessageHandler);
            app.MqttEPOSAngleRead_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/angle',0,@app.MessageHandler);
            app.MqttEPOSMaxFollowErrRead_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/MAX_FOLLOWING_ERROR',0,@app.MessageHandler);
            app.MqttEPOS_EMCY_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/EMCY',0,@app.MessageHandler);
            %MQTT_BATTERY
            app.MqttBATTERY_TS = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/TS/read',0,@app.MessageHandler);
            app.MqttBATTERYCellVoltageHighest = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/cell_voltage_highest',0,@app.MessageHandler);
            app.MqttBATTERYCellVoltageAverege = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/cell_voltage_averege',0,@app.MessageHandler);
            app.MqttBATTERYCellVoltageLowest = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/cell_voltage_lowest',0,@app.MessageHandler);
            app.MqttBATTERYChargeSOC = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/charge_SOC',0,@app.MessageHandler);
            app.MqttBATTERYChargeVoltage = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/charge_voltage',0,@app.MessageHandler);
            app.MqttBATTERYChargeCurrent = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/charge_current',0,@app.MessageHandler);
            app.MqttBATTERYCellTempHighest = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/cell_temp_highest',0,@app.MessageHandler);
            app.MqttBATTERYCellTempAverege = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/cell_temp_averege',0,@app.MessageHandler);
            app.MqttBATTERYCellTempLowest = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/cell_temp_lowest',0,@app.MessageHandler);
            app.MqttBATTERYEnergyCurrentMode = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/energy_current_mode',0,@app.MessageHandler);
            app.MqttBATTERYEnergyRate = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/energy_rate',0,@app.MessageHandler);
            app.MqttBATTERYEnergyBalancingRate = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/energy_balancing_rate',0,@app.MessageHandler);
            app.MqttBATTERYSlaveTempHighestuP = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/slave_temp_highest_uP',0,@app.MessageHandler);
            app.MqttBATTERYSlaveTempHighestLoad = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/slave_temp_highest_load',0,@app.MessageHandler);
            app.Mqtt_.unsubscribeAll
            %MQTTSubscription
            app.MqttSubLog_ = app.Mqtt_.MQTTSubscribe('VIENA/General/log',0,@app.MessageHandler);
            app.MqttSubrpi_ = app.Mqtt_.MQTTSubscribe('VIENA/General/rpi',0,@app.MessageHandler);
            app.MqttSubCan_ = app.Mqtt_.MQTTSubscribe('VIENA/General/canopen',0,@app.MessageHandler);
            %MQTTSinamics
            app.MqttSINStateRead_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/state/read',0,@app.MessageHandler);
            app.MqttSINTargetVelocityRead_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/target_velocity/read',0,@app.MessageHandler);
            app.MqttSINVofVoltageRead_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/vof_voltage/read',0,@app.MessageHandler);
            app.MqttSINVofFreqRead_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/vof_freq/read',0,@app.MessageHandler);
            app.MqttSIN_EMCY_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/EMCY',0,@app.MessageHandler);
            app.MqttSINVelocity_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/velocity',0,@app.MessageHandler);
            app.MqttSINTemperature_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/temperature',0,@app.MessageHandler);
            app.MqttSINCurrent_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/current_smoothed',0,@app.MessageHandler);
            app.MqttSINConnected_ = app.Mqtt_.MQTTSubscribe('VIENA/SINAMICS/connected',0,@app.MessageHandler);
            %MQTT_EPOS
            app.MqttEPOSConnected_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/connected',0,@app.MessageHandler);
            app.MqttEPOSStateRead_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/state',0,@app.MessageHandler);
            app.MqttEPOS_PID_Read_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/PID',0,@app.MessageHandler);
            app.MqttEPOSTargetPositionRead_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/target_position',0,@app.MessageHandler);
            app.MqttEPOSPosition_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/position',0,@app.MessageHandler);
            app.MqttEPOSAngleRead_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/angle',0,@app.MessageHandler);
            app.MqttEPOSMaxFollowErrRead_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/MAX_FOLLOWING_ERROR',0,@app.MessageHandler);
            app.MqttEPOS_EMCY_ = app.Mqtt_.MQTTSubscribe('VIENA/EPOS/EMCY',0,@app.MessageHandler);
            %MQTT_BATTERY
            app.MqttBATTERY_TS = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/TS/read',0,@app.MessageHandler);
            app.MqttBATTERYCellVoltageHighest = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/cell_voltage_highest',0,@app.MessageHandler);
            app.MqttBATTERYCellVoltageAverege = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/cell_voltage_averege',0,@app.MessageHandler);
            app.MqttBATTERYCellVoltageLowest = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/cell_voltage_lowest',0,@app.MessageHandler);
            app.MqttBATTERYChargeSOC = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/charge_SOC',0,@app.MessageHandler);
            app.MqttBATTERYChargeVoltage = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/charge_voltage',0,@app.MessageHandler);
            app.MqttBATTERYChargeCurrent = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/charge_current',0,@app.MessageHandler);
            app.MqttBATTERYCellTempHighest = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/cell_temp_highest',0,@app.MessageHandler);
            app.MqttBATTERYCellTempAverege = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/cell_temp_averege',0,@app.MessageHandler);
            app.MqttBATTERYCellTempLowest = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/cell_temp_lowest',0,@app.MessageHandler);
            app.MqttBATTERYEnergyCurrentMode = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/energy_current_mode',0,@app.MessageHandler);
            app.MqttBATTERYEnergyRate = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/energy_rate',0,@app.MessageHandler);
            app.MqttBATTERYEnergyBalancingRate = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/energy_balancing_rate',0,@app.MessageHandler);
            app.MqttBATTERYSlaveTempHighestuP = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/slave_temp_highest_uP',0,@app.MessageHandler);
            app.MqttBATTERYSlaveTempHighestLoad = app.Mqtt_.MQTTSubscribe('VIENA/BATTERY/slave_temp_highest_load',0,@app.MessageHandler);
        end
    
    end
    

    methods (Access = private)

        % Value changed function: EnableSwitch
        function EnableSwitchValueChanged(app, event)
            changeEnableSwitch(app);
        end

        % Value changed function: VoF
        function VoFValueChanged(app, event)
            refresh(app)
        end

        % Value changed function: statorSpeed
        function statorSpeedValueChanged(app, event)
            app.NsSlider.Value=app.statorSpeed.Value;
            refresh(app)
        end

        % Value changed function: loadTorque
        function loadTorqueValueChanged(app, event)
            refresh(app)
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.STOPButton.UserData = true;
            app.switchOffAll;
        end

        % Callback function
        function NsSliderValueChanged(app, event)
            app.statorSpeed.Value=app.NsSlider.Value;
            refresh(app)
        end

        % Value changed function: sweepCheckBox
        function sweepCheckBoxValueChanged(app, event)
            if app.sweepCheckBox.Value
                app.sweepStatorSpeed.Enable = 'On';
            else
                app.sweepStatorSpeed.Enable = 'Off';
            end
        end

        % Value changed function: sweepStatorSpeed
        function sweepStatorSpeedValueChanged(app, event)
            refresh(app)
        end

        % Button pushed function: BrokerConnectButton
        function BrokerConnectButtonPushed(app, event)
            if app.brokerConnected_
                if app.Mqtt_.Connected
                    app.Mqtt_.delete;
                    app.BrokerLamp.Color = [1 0 0];
                    app.BrokerConnectButton.Text = 'Connect';
                    app.ServerAddress.Enable = 'On';
                    app.ServerPort.Enable = 'On';
                    app.brokerConnected_ = false;
                end
            else
                try
                    app.Mqtt_ = VIENAGUI.MQTTController('ws',app.ServerAddress.Value,app.ServerPort.Value);
                catch
                    uialert(app.VIENAUIFigure,'MQTT: failed to connect','Warning','Icon','warning');
                    return
                end
                if app.Mqtt_.Connected
                    app.BrokerLamp.Color = [0 1 0];
                    app.BrokerConnectButton.Text = 'Disconnect';
                    app.ServerAddress.Enable = 'Off';
                    app.ServerPort.Enable = 'Off';
                    app.brokerConnected_ = true;
                    app.MQTTSubscribeAll;
                end
            end
        end

        % Button pushed function: LogoButton
        function LogoButtonPushed(app, event)
            web('https://github.com/VIENA-IST','-browser');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VIENAUIFigure = uifigure;
            app.VIENAUIFigure.Name = 'VIENA';
            app.TabGroup = uitabgroup(app.VIENAUIFigure);
            app.MOTORTab = uitab(app.TabGroup);
            app.MOTORTab.Title = 'MOTOR';
            app.MotorMeasurementsPanel = uipanel(app.MOTORTab);
            app.MotorMeasurementsPanel.Title = 'Motor Measurements';
            app.NrrpmEditFieldLabel = uilabel(app.MotorMeasurementsPanel);
            app.NrrpmEditFieldLabel.Text = 'Nr [rpm]';
            app.Nr_model = uieditfield(app.MotorMeasurementsPanel, 'numeric');
            app.Nr_model.Editable = 'off';
            app.IsAEditFieldLabel = uilabel(app.MotorMeasurementsPanel);
            app.IsAEditFieldLabel.Text = 'Is [A]';
            app.Is_model = uieditfield(app.MotorMeasurementsPanel, 'numeric');
            app.Is_model.Editable = 'off';
            app.UsVEditFieldLabel = uilabel(app.MotorMeasurementsPanel);
            app.UsVEditFieldLabel.Text = 'Us [V]';
            app.Us_model = uieditfield(app.MotorMeasurementsPanel, 'numeric');
            app.Us_model.Editable = 'off';
            app.fHzEditField_2Label = uilabel(app.MotorMeasurementsPanel);
            app.fHzEditField_2Label.Text = 'f [Hz]';
            app.f_model = uieditfield(app.MotorMeasurementsPanel, 'numeric');
            app.f_model.Editable = 'off';
            app.UIAxes = uiaxes(app.MOTORTab);
            title(app.UIAxes, 'Torque Curve')
            xlabel(app.UIAxes, 'Nr [rpm]')
            ylabel(app.UIAxes, 'T [Nm]')
            app.UIAxes.XLim = [0 9000];
            app.UIAxes.YLim = [0 180];
            app.CircuitInputsPanel = uipanel(app.MOTORTab);
            app.CircuitInputsPanel.Title = 'Circuit Inputs';
            app.T_loadEditFieldLabel = uilabel(app.CircuitInputsPanel);
            app.T_loadEditFieldLabel.Text = 'T_load';
            app.loadTorque = uieditfield(app.CircuitInputsPanel, 'numeric');
            app.loadTorque.Limits = [0 Inf];
            app.loadTorque.ValueChangedFcn = createCallbackFcn(app, @loadTorqueValueChanged, true);
            app.loadTorque.Enable = 'off';
            app.EnableSwitch = uiswitch(app.MOTORTab, 'slider');
            app.EnableSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSwitchValueChanged, true);
            app.STOPButton = uibutton(app.MOTORTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = '[ STOP ]';
            app.MotorInputsPanel = uipanel(app.MOTORTab);
            app.MotorInputsPanel.Title = 'Motor  Inputs';
            app.VfEditFieldLabel = uilabel(app.MotorInputsPanel);
            app.VfEditFieldLabel.Text = 'V/f';
            app.VoF = uieditfield(app.MotorInputsPanel, 'numeric');
            app.VoF.Limits = [0 Inf];
            app.VoF.ValueChangedFcn = createCallbackFcn(app, @VoFValueChanged, true);
            app.VoF.Enable = 'off';
            app.NsrpmLabel = uilabel(app.MotorInputsPanel);
            app.NsrpmLabel.Text = 'Ns [rpm]';
            app.statorSpeed = uieditfield(app.MotorInputsPanel, 'numeric');
            app.statorSpeed.ValueChangedFcn = createCallbackFcn(app, @statorSpeedValueChanged, true);
            app.statorSpeed.Enable = 'off';
            app.sweepCheckBox = uicheckbox(app.MotorInputsPanel);
            app.sweepCheckBox.ValueChangedFcn = createCallbackFcn(app, @sweepCheckBoxValueChanged, true);
            app.sweepCheckBox.Enable = 'off';
            app.sweepCheckBox.Text = 'Sweep';
            app.sweepStatorSpeed = uieditfield(app.MotorInputsPanel, 'numeric');
            app.sweepStatorSpeed.Limits = [0 Inf];
            app.sweepStatorSpeed.ValueChangedFcn = createCallbackFcn(app, @sweepStatorSpeedValueChanged, true);
            app.sweepStatorSpeed.Enable = 'off';
            app.EnableLampLabel = uilabel(app.MOTORTab);
            app.EnableLampLabel.Text = 'Enable';
            app.EnableLamp = uilamp(app.MOTORTab);
            app.SINAMICSTab = uitab(app.TabGroup);
            app.SINAMICSTab.Title = 'SINAMICS';
            app.SINAMICSPanel = uipanel(app.SINAMICSTab);
            app.SINAMICSPanel.Title = 'SINAMICS';
            app.EMCYLamp = uilamp(app.SINAMICSPanel);
            app.VFMinEditFieldLabel = uilabel(app.SINAMICSPanel);
            app.VFMinEditFieldLabel.Text = 'V/F Min';
            app.VFMinEditField = uieditfield(app.SINAMICSPanel, 'numeric');
            app.VFMinEditField.Editable = 'off';
            app.TorqueEditFieldLabel = uilabel(app.SINAMICSPanel);
            app.TorqueEditFieldLabel.Text = 'Torque';
            app.TorqueEditField = uieditfield(app.SINAMICSPanel, 'numeric');
            app.TorqueEditField.Editable = 'off';
            app.CurrentEditFieldLabel = uilabel(app.SINAMICSPanel);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.CurrentEditField = uieditfield(app.SINAMICSPanel, 'numeric');
            app.CurrentEditField.Editable = 'off';
            app.VelocityEditFieldLabel = uilabel(app.SINAMICSPanel);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.SINAMICSPanel, 'numeric');
            app.VelocityEditField.Editable = 'off';
            app.VFFreqEditFieldLabel = uilabel(app.SINAMICSPanel);
            app.VFFreqEditFieldLabel.Text = 'V/F Freq';
            app.VFFreqEditField = uieditfield(app.SINAMICSPanel, 'numeric');
            app.VFFreqEditField.Editable = 'off';
            app.TargetVelocityEditFieldLabel = uilabel(app.SINAMICSPanel);
            app.TargetVelocityEditFieldLabel.Text = 'Target Velocity';
            app.TargetVelocityEditField = uieditfield(app.SINAMICSPanel, 'numeric');
            app.TargetVelocityEditField.Editable = 'off';
            app.VFVoltageEditFieldLabel = uilabel(app.SINAMICSPanel);
            app.VFVoltageEditFieldLabel.Text = 'V/F Voltage';
            app.VFVoltageEditField = uieditfield(app.SINAMICSPanel, 'numeric');
            app.VFVoltageEditField.Editable = 'off';
            app.StateEditFieldLabel = uilabel(app.SINAMICSPanel);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.SINAMICSPanel, 'text');
            app.StateEditField.Editable = 'off';
            app.EMCYEditFieldLabel = uilabel(app.SINAMICSPanel);
            app.EMCYEditFieldLabel.Text = 'EMCY';
            app.TerminalWindowPanel = uipanel(app.SINAMICSTab);
            app.TerminalWindowPanel.Title = 'Terminal Window';
            app.TerminalWindowText = uitextarea(app.SINAMICSTab);
            app.EPOSTab = uitab(app.TabGroup);
            app.EPOSTab.Title = 'EPOS';
            app.EPOSDeviceControlPanel = uipanel(app.EPOSTab);
            app.EPOSDeviceControlPanel.Title = 'Device Control';
            app.EPOSFaultReset = uibutton(app.EPOSDeviceControlPanel, 'push');
            app.EPOSFaultReset.Text = 'Fault Reset';
            app.EPOSActivate = uibutton(app.EPOSDeviceControlPanel, 'push');
            app.EPOSActivate.Text = 'Activate';
            app.EPOSShutdown = uibutton(app.EPOSDeviceControlPanel, 'push');
            app.EPOSShutdown.Text = 'Shutdown';
            app.EPOSHalt = uibutton(app.EPOSDeviceControlPanel, 'push');
            app.EPOSHalt.Text = 'Halt';
            app.EPOSStatePanel = uipanel(app.EPOSDeviceControlPanel);
            app.EPOSStatePanel.Title = 'EPOS State';
            app.EPOSState = uitextarea(app.EPOSStatePanel);
            app.EPOSState.Editable = 'off';
            app.EPOSEnablePositionMode = uibutton(app.EPOSDeviceControlPanel, 'state');
            app.EPOSEnablePositionMode.Text = 'Enable Position Mode';
            app.EPOSPIDGainsPanel = uipanel(app.EPOSTab);
            app.EPOSPIDGainsPanel.Title = 'PID Gains';
            app.EPOSApplyPIDGains = uibutton(app.EPOSPIDGainsPanel, 'push');
            app.EPOSApplyPIDGains.Text = 'Apply';
            app.PEditFieldLabel = uilabel(app.EPOSPIDGainsPanel);
            app.PEditFieldLabel.Text = 'P';
            app.EPOS_P_Gain = uieditfield(app.EPOSPIDGainsPanel, 'numeric');
            app.IEditFieldLabel = uilabel(app.EPOSPIDGainsPanel);
            app.IEditFieldLabel.Text = 'I';
            app.EPOS_I_Gain = uieditfield(app.EPOSPIDGainsPanel, 'numeric');
            app.DEditFieldLabel = uilabel(app.EPOSPIDGainsPanel);
            app.DEditFieldLabel.Text = 'D';
            app.EPOS_D_Gain = uieditfield(app.EPOSPIDGainsPanel, 'numeric');
            app.MaxFollowingErrorPanel = uipanel(app.EPOSTab);
            app.MaxFollowingErrorPanel.Title = 'Max Following Error';
            app.EPOSMaxFollowingError = uieditfield(app.MaxFollowingErrorPanel, 'numeric');
            app.EPOSApplyMaxFolloingError = uibutton(app.MaxFollowingErrorPanel, 'push');
            app.EPOSApplyMaxFolloingError.Text = 'Apply';
            app.EPOSCurrentPositionPanel = uipanel(app.EPOSTab);
            app.EPOSCurrentPositionPanel.Title = 'Current Position';
            app.EPOSCurrentPosition = uitextarea(app.EPOSCurrentPositionPanel);
            app.EPOSCurrentPosition.Editable = 'off';
            app.EPOSCurrentPositionGauge = uigauge(app.EPOSCurrentPositionPanel, 'circular');
            app.EPOSCurrentPositionGauge.Limits = [-30 30];
            app.EPOSSetPositionPanel = uipanel(app.EPOSTab);
            app.EPOSSetPositionPanel.Title = 'Set Position';
            app.EPOSSetPosition = uibutton(app.EPOSSetPositionPanel, 'push');
            app.EPOSSetPosition.Text = 'Set Position';
            app.EPOSSetPositionValue = uieditfield(app.EPOSSetPositionPanel, 'numeric');
            app.EPOSLogPanel = uipanel(app.EPOSTab);
            app.EPOSLogPanel.Title = 'Log';
            app.EPOSLog = uitextarea(app.EPOSLogPanel);
            app.EPOSLog.Editable = 'off';
            app.BATTERYTab = uitab(app.TabGroup);
            app.BATTERYTab.Title = 'BATTERY';
            app.TSonoffButton = uibutton(app.BATTERYTab, 'push');
            app.TSonoffButton.Text = 'TS on/off';
            app.ResetErrorButton = uibutton(app.BATTERYTab, 'push');
            app.ResetErrorButton.Text = 'Reset Error';
            app.OverrideButton = uibutton(app.BATTERYTab, 'push');
            app.OverrideButton.Text = 'Override';
            app.TSOnLampLabel = uilabel(app.BATTERYTab);
            app.TSOnLampLabel.Text = 'TS On';
            app.TSOnLamp = uilamp(app.BATTERYTab);
            app.BMS1ErrorLampLabel = uilabel(app.BATTERYTab);
            app.BMS1ErrorLampLabel.Text = 'BMS 1 Error';
            app.BMS1ErrorLamp = uilamp(app.BATTERYTab);
            app.BMS2ErrorLampLabel = uilabel(app.BATTERYTab);
            app.BMS2ErrorLampLabel.Text = 'BMS 2 Error';
            app.BMS2ErrorLamp = uilamp(app.BATTERYTab);
            app.ChargePanel = uipanel(app.BATTERYTab);
            app.ChargePanel.Title = 'Charge';
            app.SOCGaugeLabel = uilabel(app.ChargePanel);
            app.SOCGaugeLabel.Text = 'SOC';
            app.SOCGauge = uigauge(app.ChargePanel, 'linear');
            app.CurrentValue = uilabel(app.ChargePanel);
            app.CurrentValue.Text = '';
            app.CurrentLabel = uilabel(app.ChargePanel);
            app.CurrentLabel.Text = 'Current';
            app.VoltageLabel = uilabel(app.ChargePanel);
            app.VoltageLabel.Text = 'Voltage';
            app.VoltegeValue = uilabel(app.ChargePanel);
            app.VoltegeValue.Text = '';
            app.EnergyPanel = uipanel(app.BATTERYTab);
            app.EnergyPanel.Title = 'Energy';
            app.CurrentModeLabel = uilabel(app.EnergyPanel);
            app.CurrentModeLabel.Text = 'Current Mode';
            app.RateLabel = uilabel(app.EnergyPanel);
            app.RateLabel.Text = 'Rate';
            app.BalancingRateLabel = uilabel(app.EnergyPanel);
            app.BalancingRateLabel.Text = 'Balancing Rate';
            app.CurrentRateValue = uilabel(app.EnergyPanel);
            app.CurrentRateValue.Text = '';
            app.RateValue = uilabel(app.EnergyPanel);
            app.RateValue.Text = '';
            app.BalancingRateValue = uilabel(app.EnergyPanel);
            app.BalancingRateValue.Text = '';
            app.CellVoltagesPanel = uipanel(app.BATTERYTab);
            app.CellVoltagesPanel.Title = 'Cell Voltages';
            app.HighestCellVoltLabel = uilabel(app.CellVoltagesPanel);
            app.HighestCellVoltLabel.Text = 'Highest';
            app.AveregeCellVoltLabel = uilabel(app.CellVoltagesPanel);
            app.AveregeCellVoltLabel.Text = 'Averege';
            app.LowestCellVoltLabel = uilabel(app.CellVoltagesPanel);
            app.LowestCellVoltLabel.Text = 'Lowest';
            app.HighestCellVoltValue = uilabel(app.CellVoltagesPanel);
            app.HighestCellVoltValue.Text = '';
            app.AveregeCellVoltValue = uilabel(app.CellVoltagesPanel);
            app.AveregeCellVoltValue.Text = '';
            app.LowestCellVoltValue = uilabel(app.CellVoltagesPanel);
            app.LowestCellVoltValue.Text = '';
            app.CellTemperaturesPanel = uipanel(app.BATTERYTab);
            app.CellTemperaturesPanel.Title = 'Cell Temperatures';
            app.HighestCellTempLabel = uilabel(app.CellTemperaturesPanel);
            app.HighestCellTempLabel.Text = 'Highest';
            app.AveregeCellTempLabel = uilabel(app.CellTemperaturesPanel);
            app.AveregeCellTempLabel.Text = 'Averege';
            app.LowestCellTempLabel = uilabel(app.CellTemperaturesPanel);
            app.LowestCellTempLabel.Text = 'Lowest';
            app.HighestCellTempValue = uilabel(app.CellTemperaturesPanel);
            app.HighestCellTempValue.Text = '';
            app.AveregeCellTempValue = uilabel(app.CellTemperaturesPanel);
            app.AveregeCellTempValue.Text = '';
            app.LowestCellTempValue = uilabel(app.CellTemperaturesPanel);
            app.LowestCellTempValue.Text = '';
            app.SlaveTemperaturesPanel = uipanel(app.BATTERYTab);
            app.SlaveTemperaturesPanel.Title = 'Slave Temperatures';
            app.HighestUPLabel = uilabel(app.SlaveTemperaturesPanel);
            app.HighestUPLabel.Text = 'Highest uP';
            app.HighestLoadLabel = uilabel(app.SlaveTemperaturesPanel);
            app.HighestLoadLabel.Text = 'Highest Load';
            app.HighestUPValue = uilabel(app.SlaveTemperaturesPanel);
            app.HighestUPValue.Text = '';
            app.HighestLoadValue = uilabel(app.SlaveTemperaturesPanel);
            app.HighestLoadValue.Text = '';
            app.LogoButton = uibutton(app.VIENAUIFigure, 'push');
            app.LogoButton.ButtonPushedFcn = createCallbackFcn(app, @LogoButtonPushed, true);
            app.LogoButton.Icon = 'vienalogo.jpg';
            app.LogoButton.Text = '';
            app.MQTTLabel = uilabel(app.VIENAUIFigure);
            app.MQTTLabel.Text = 'MQTT';
            app.BatteriesLampLabel = uilabel(app.VIENAUIFigure);
            app.BatteriesLampLabel.Text = 'Batteries';
            app.BatteriesLamp = uilamp(app.VIENAUIFigure);
            app.RPiLampLabel = uilabel(app.VIENAUIFigure);
            app.RPiLampLabel.Text = 'RPi';
            app.RPiLamp = uilamp(app.VIENAUIFigure);
            app.CANLampLabel = uilabel(app.VIENAUIFigure);
            app.CANLampLabel.Text = 'CAN';
            app.CANLamp = uilamp(app.VIENAUIFigure);
            app.BrokerLampLabel = uilabel(app.VIENAUIFigure);
            app.BrokerLampLabel.Text = 'Broker';
            app.BrokerLamp = uilamp(app.VIENAUIFigure);
            app.Label = uilabel(app.VIENAUIFigure);
            app.Label.Text = ':';
            app.BrokerConnectButton = uibutton(app.VIENAUIFigure, 'push');
            app.BrokerConnectButton.ButtonPushedFcn = createCallbackFcn(app, @BrokerConnectButtonPushed, true);
            app.BrokerConnectButton.Text = 'Connect';
            app.ServerPort = uieditfield(app.VIENAUIFigure, 'numeric');
            app.ServerPort.Value = 8080;
            app.ServerAddress = uieditfield(app.VIENAUIFigure, 'text');
            app.ServerAddress.Value = '192.168.31.56';
        end
    end

    methods (Access = public)

        % Construct app
        function app = viena_R2017b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VIENAUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VIENAUIFigure)
        end
    end
end